classdef cvApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        SettingsMenu                matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        MainContentPanel            matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        CalculateDifferencesButton  matlab.ui.control.Button
        VisualizationDropDown       matlab.ui.control.DropDown
        VisualizationDropDownLabel  matlab.ui.control.Label
        CalculateOverlayButton      matlab.ui.control.Button
        OpenButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)        
        CorrData
        CurrentIndex
    end
    
    properties (Access = public)
        Views       % struct of all available views for the app
        OverlayClass   % class for all imagedata and the overlay transformations
        DifferenceClass % class for difference data and calculation
    end
    
    methods (Access = private)

        function updateTable(app)
            if app.CurrentIndex > numel(app.CorrData)
                uialert(app.UIFigure, 'Alle Korrespondenzen wurden angezeigt.', 'Fertig');
                return;
            end
        
            data = app.CorrData{app.CurrentIndex};
            tbl = table(data.pts1(:,1), data.pts1(:,2), ...
                        data.pts2(:,1), data.pts2(:,2), ...
                        'VariableNames', {'x1', 'y1', 'x2', 'y2'});
        
            app.PointTable.Data = tbl;
        end
        
        function loadViews(app)
            app.Views = getViews(app);
            viewNames = fieldnames(app.Views);
            % Update dropdown options
            app.VisualizationDropDown.Items = viewNames;
            if ~isempty(viewNames)
                app.VisualizationDropDown.Value = "Overlay";
                app.Views.("Overlay").show();  % Show initial view
            end
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % include all required files and classes and fucntions and
            % stuff
            addpath(fullfile(pwd, 'src'));
            addpath(fullfile(pwd, 'src/visual'));
            addpath(fullfile(pwd, 'src/overlay'));

            app.CalculateOverlayButton.Text = "No data!";
            app.CalculateDifferencesButton.Text = "No data!";
            app.CalculateDifferencesButton.Visible = "off";

            loadViews(app);  % Dynamically register all views
            %app.mic = MultiImageCorrespondence(); % loads spreadsheet object for correspondence vis
        end

        % Value changed function: VisualizationDropDown
        function VisualizationDropDownValueChanged(app, event)
            selected = app.VisualizationDropDown.Value;
        
            % Hide all views
            viewNames = fieldnames(app.Views);
            for k = 1:numel(viewNames)
                app.Views.(viewNames{k}).hide();
            end
        
            % Show selected
            if isfield(app.Views, selected)
                if ( selected == "Difference" ) && ( ~isvalid(app.OverlayClass) || isempty(app.OverlayClass.lastIndices) )
                    uialert(app.UIFigure, 'No Overlay calculated yet, do that first to enable proper difference visualization.', 'Warning');
                end
                app.Views.(selected).show();
            end

            if app.VisualizationDropDown.ValueIndex == 2
                app.CalculateDifferencesButton.Visible = "on";
            else
                app.CalculateDifferencesButton.Visible = "off";
            end
        end

        % Callback function: OpenButton, OpenMenu
        function OpenMenuSelected(app, event)
           
            images = utils.loadImagesFromFolder();
            
            disp(class(images))
            if isempty(images)
                uialert(app.UIFigure, 'No valid images found with pattern YYYY_MM.jpg', 'No Images');
                return;
            else
                % if new images selected reset view to overlay
                viewNames = fieldnames(app.Views);
                for k = 1:numel(viewNames)
                    app.Views.(viewNames{k}).hide();
                end
                app.VisualizationDropDown.Value = "Overlay";
                app.Views.("Overlay").reset();
                app.Views.("Difference").reset();

                app.Views.("Overlay").show();
            end

            app.OverlayClass = calcOverlay(images);

            app.DifferenceClass = differenceEstimationFunctions(app.OverlayClass);

            app.CalculateOverlayButton.Text = "Calculate Overlay";
            app.CalculateOverlayButton.Enable = 'on';

            app.CalculateDifferencesButton.Text = "No Overlay!";
            app.CalculateDifferencesButton.Enable = 'off';

            % Update views, etc.
            viewNames = fieldnames(app.Views);
            for k = 1:numel(viewNames)
                app.Views.(viewNames{k}).onImLoad();
            end
            
            selected = app.VisualizationDropDown.Value;
            if isfield(app.Views, selected)
                app.Views.(selected).update();
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app.UIFigure)
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.tif'}, 'Bild speichern als');

            if ischar(file)
                exportgraphics(app.UIAxes, fullfile(path, file));
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            helpFile = fullfile(pwd, 'help.html');
            web(helpFile, '-browser')
        end

        % Button pushed function: CalculateOverlayButton
        function CalcOverlay(app, event)
            app.CalculateOverlayButton.Text = "Calculating...";
            app.CalculateOverlayButton.Enable = 'off';
            app.Views.Overlay.calculate();
            app.CalculateOverlayButton.Enable = 'on';
            app.CalculateOverlayButton.Text = "Calculate Overlay";
        end

        % Button pushed function: CalculateDifferencesButton
        function CalcDifferences(app, event)
            app.CalculateDifferencesButton.Text = "Calculating...";
            app.CalculateDifferencesButton.Enable = 'off';
            app.Views.Difference.calculate();
            app.CalculateDifferencesButton.Enable = 'on';
            app.CalculateDifferencesButton.Text = "Calculate Differences";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.HelpMenu = uimenu(app.SettingsMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OpenButton = uibutton(app.GridLayout2, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenButton.Text = 'Open';
            app.CalculateOverlayButton = uibutton(app.GridLayout2, 'push');
            app.CalculateOverlayButton.ButtonPushedFcn = createCallbackFcn(app, @CalcOverlay, true);
            app.CalculateOverlayButton.Enable = 'off';
            app.CalculateOverlayButton.Text = 'Calculate Overlay';
            app.VisualizationDropDownLabel = uilabel(app.GridLayout2);
            app.VisualizationDropDownLabel.Text = 'Visualization';
            app.VisualizationDropDown = uidropdown(app.GridLayout2);
            app.VisualizationDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizationDropDownValueChanged, true);
            app.CalculateDifferencesButton = uibutton(app.GridLayout2, 'push');
            app.CalculateDifferencesButton.ButtonPushedFcn = createCallbackFcn(app, @CalcDifferences, true);
            app.CalculateDifferencesButton.Enable = 'off';
            app.CalculateDifferencesButton.Text = 'Calculate Differences';
            app.MainContentPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cvApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end